classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotTabs                       matlab.ui.container.TabGroup
        WaveformsTab                   matlab.ui.container.Tab
        ResultsPlot2                   matlab.ui.control.UIAxes
        ResultsPlot                    matlab.ui.control.UIAxes
        ProcessedWaveformsTab          matlab.ui.container.Tab
        ProcessedWaveforms4            matlab.ui.control.UIAxes
        ProcessedWaveforms3            matlab.ui.control.UIAxes
        ProcessedWaveforms2            matlab.ui.control.UIAxes
        ProcessedWaveforms             matlab.ui.control.UIAxes
        BHCurveTab                     matlab.ui.container.Tab
        BHCurve                        matlab.ui.control.UIAxes
        SimulationTab                  matlab.ui.container.Tab
        dtSlider                       matlab.ui.control.Slider
        dtSliderLabel                  matlab.ui.control.Label
        alphaSlider                    matlab.ui.control.Slider
        alphaSliderLabel               matlab.ui.control.Label
        kpSlider                       matlab.ui.control.Slider
        kpSliderLabel                  matlab.ui.control.Label
        crSlider                       matlab.ui.control.Slider
        crSliderLabel                  matlab.ui.control.Label
        MsSlider                       matlab.ui.control.Slider
        MsSliderLabel                  matlab.ui.control.Label
        aSlider                        matlab.ui.control.Slider
        aSliderLabel                   matlab.ui.control.Label
        simEnergy                      matlab.ui.control.UIAxes
        simBHPlot                      matlab.ui.control.UIAxes
        SettingsTabs                   matlab.ui.container.TabGroup
        RunTab                         matlab.ui.container.Tab
        OscilloscopeOffButton          matlab.ui.control.Button
        FolderToSaveToEditField        matlab.ui.control.EditField
        FolderToSaveToEditFieldLabel   matlab.ui.control.Label
        constModeToggle                matlab.ui.control.Button
        SaveResultsPlotsButton         matlab.ui.control.Button
        ResultsLabel                   matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        runLengthButton                matlab.ui.control.NumericEditField
        RunLengthsLabel                matlab.ui.control.Label
        PresstoConnecttheAudioInterfaceButton  matlab.ui.control.Button
        SourcesTab                     matlab.ui.container.Tab
        ndCHAmplitudeEditField         matlab.ui.control.NumericEditField
        ndCHAmplitudeEditFieldLabel    matlab.ui.control.Label
        PhaseOffset                    matlab.ui.control.NumericEditField
        ndCHPhaseOffsetdegEditFieldLabel  matlab.ui.control.Label
        stCHAmplitudeEditField         matlab.ui.control.NumericEditField
        stCHAmplitudeEditFieldLabel    matlab.ui.control.Label
        SPWMCarrierFreqHzEditField     matlab.ui.control.NumericEditField
        SPWMCarrierFreqHzEditFieldLabel  matlab.ui.control.Label
        FrequencyHzEditField           matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        OscilationTypeDropDown         matlab.ui.control.DropDown
        OscilationTypeDropDownLabel    matlab.ui.control.Label
        AdvTab                         matlab.ui.container.Tab
        AudioOutputChannel2DropDown    matlab.ui.control.DropDown
        AudioOutputChannel2DropDownLabel  matlab.ui.control.Label
        AudioInputChannel2DropDown     matlab.ui.control.DropDown
        AudioInputChannel2Label        matlab.ui.control.Label
        AudioInputChannel1DropDown     matlab.ui.control.DropDown
        AudioInputChannel1DropDownLabel  matlab.ui.control.Label
        CheckForNewAudioDevicesButton  matlab.ui.control.Button
        AudioOutputChannel1DropDown    matlab.ui.control.DropDown
        AudioOuputChannel1Label        matlab.ui.control.Label
        SampleRateEditField            matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel       matlab.ui.control.Label
        BufferSizeDropDown             matlab.ui.control.DropDown
        BufferSizeDropDownLabel        matlab.ui.control.Label
        Plan                           matlab.ui.container.Tab
        loadCurrentConfig              matlab.ui.control.Button
        ExpConfigFilenameEditField     matlab.ui.control.EditField
        ExpConfigFilenameEditFieldLabel  matlab.ui.control.Label
        saveCurrentConfig              matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %
    % The main measurment loop is with the runMeasurement callback, with
    % the analysis being within the dataAnalysis function
    %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    properties (Access = public)
        aPR
        aDR
        aDW
        aDR2
        aDW2
        SPWMCarrFreq
        startFreq
        osciType
        runLength
        bufferSize
        phaseOffset
        stCHAmplitude
        ndCHAmplitude
        Input1Buffer
        Input2Buffer
        Output1Buffer
        Output2Buffer
        Time
        AnalysisTimer
        runTimer
        constRun
        subfolder
        sampleRate
        configFilename
        oscilloscope
        dt
        alpha
        a
        cr
        Ms
        kp
        inputCh1
        inputCh2
        outputCh1
        outputCh2
    end

    methods (Access = public)

        function logMessage(app,message)                                      % Opens log.txt, prints message, after a time stamp, closes the txt file
                logFile = fopen('log.txt', 'a');
                fprintf(logFile, '[%s] %s\n', datetime("now"), message);
                fclose(logFile);
        end

        function wipeLogFile(~)                                             % Opens log.txt, clears the file, closes log.txt
            fclose(fopen('log.txt', 'w'));
        end

        function Source = generateSource(app,offset,amp)
            try
                if strcmp(app.osciType, 'White Noise')                      % First option is white noise, only adjustable element is the buffer size
                    Source = dsp.ColoredNoise(0,app.bufferSize,1,'BoundedOutput',true);
                elseif strcmp(app.osciType, 'SPWM')                         % Second option is SPWM, uses custom class file in SPWMOsc.m, has adjustable elements
                    Source = SPWMOsc('ReferenceFrequency', app.startFreq, ...
                        'CarrierFrequency', app.SPWMCarrFreq, ...
                        'SampleRate', app.sampleRate, ...
                        'SamplesPerFrame', app.bufferSize, ...
                        'ReferenceAmplitude',amp, ...
                        'PhaseOffset', offset);
                else                                                        % All other oscilation types are handled by the audioOscillator funciton in MATLAB, offset needs to be converted from degrees to a fraction.
                    offset = offset/360;
                    Source = audioOscillator(Amplitude=amp,Frequency=app.startFreq,SignalType=app.osciType,SampleRate=app.sampleRate,SamplesPerFrame=app.bufferSize,PhaseOffset=offset);
                end

            catch ME
                logMessage(app,['Error within generateSource function! - ', ME.message])
            end
        end

    end


    methods (Access = private)

        
        function [] = osciloscopeHelperFunc(app)
            if app.oscilloscope == 0
                return
            end

            if ~strcmp(app.PresstoConnecttheAudioInterfaceButton.Text, 'Connected') % Check if audio interface is connected
                    app.RunButton.Text = 'No Connection to the Audio Interface';
                    return
                end

                if strcmp(app.RunButton.Text, 'Run in Progress')                        % Check if there is already a run in progress
                    app.RunButton.Text = 'Already Running!';
                    app.constRun = 0;
                    return
                end

            Source = generateSource(app,0,app.stCHAmplitude);                       % Create sources
                SourceOffset = generateSource(app,app.phaseOffset,app.ndCHAmplitude);
                lockout = 0;


                inputBuffer = [];                                                       % Initialise variables
                input2Buffer = [];
                outputBuffer = [];
                output2Buffer = [];
                output = [];
                construn = app.constRun;
                i = 0;
                lastEndTime = 0;

                while lockout == 0                                                      % Enter a loop, and begin to play audio. Check if audio is being recieved, and escape the loop when audio is recieved.
                    x = Source();
                    x2 = SourceOffset();
                    app.aDW(x);
                    app.aDW2(x2)
                    temp = app.aDR();
                    output = [output; [temp,temp]];
                    if any(abs(output(:,1)) >= 0.002)                   %%% Potential bug here, if the recieved audio has a scale factor of 0.8 due to the physical system, lockout may not be triggered. Perhaps a better solution is needed.
                        lockout = 1;

                    end
                end
                app.runTimer = tic;                                                     % Start experimental timer


                while app.oscilloscope == 1           % Check if expermintal timer is less than requested run length, OR continuous mode is enabled
                    
                    x = Source();
                    x2 = SourceOffset();                                                % Generate new audio sources

                    app.aDW(x);
                    app.aDW2(x2);
                    temp = app.aDR();                                     % Transmit and recieve audio, store these values in a buffer
                    temp2 = app.aDR2();
                        inputBuffer = [x];
                        input2Buffer = [x2];
                        outputBuffer = [temp];
                        output2Buffer = [temp2];

                    
                    endTime = toc(app.runTimer);
                    Source.Frequency = app.startFreq;
                    Source.Amplitude = app.stCHAmplitude;
                    SourceOffset.Frequency = app.startFreq;
                    SourceOffset.Amplitude = app.ndCHAmplitude;
                    % SourceOffset.PhaseOffset = app.PhaseOffset;
                    switch i                                                            % Switch between plotting each tab of the UI, reduces computation time
                        case 0
                            livePlot(app,outputBuffer,output2Buffer,inputBuffer,input2Buffer,endTime,0,lastEndTime)
                            i = 1;
                        case 1
                            livePlot(app,outputBuffer,output2Buffer,inputBuffer,input2Buffer,endTime,1,lastEndTime)
                            i = 2;
                        case 2
                            livePlot(app,outputBuffer,output2Buffer,inputBuffer,input2Buffer,endTime,2,lastEndTime)
                            i = 0;
                    end
                    lastEndTime = endTime;

                    
                    drawnow limitrate                                                    % Required to update the UI, stop it from freezing in the loop
                end

                endTime = toc(app.runTimer);                                             % Store buffers as properties of the app (i dont know if this is still needed?)
                app.Input1Buffer = inputBuffer;
                app.Input2Buffer = input2Buffer;
                app.Output1Buffer = outputBuffer;
                app.Output2Buffer = output2Buffer;


                dataAnalysis(app, app.Output1Buffer, app.Output2Buffer, app.Input1Buffer, app.Input2Buffer, endTime,lastEndTime);
                release(app.aDW);release(app.aDR);release(app.aDW2);release(app.aDR2);

        end
        
        function [] = simPlot(app)
            try
                [B,H,A,E, P] = JAModel(app);

                plot(app.simBHPlot,H,B)
                axis(app.simBHPlot,'tight')

                plot(app.simEnergy,A,E)


            catch ME
                logMessage(app,['Error within simPlot function! - ', ME.message])
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isdeployed
                targetDir = fullfile(getenv('USERPROFILE'), 'Documents', 'Transformer Test');
                if ~exist(targetDir, 'dir')
                    mkdir(targetDir);
                end
                 cd(targetDir);
            end
            try
                wipeLogFile(app)
                app.SPWMCarrFreq = 500;
                app.startFreq = 10;
                app.osciType = 'sine';
                app.runLength = 0.1;
                app.bufferSize = 192000;
                app.phaseOffset = 45;
                app.stCHAmplitude = 1;
                app.ndCHAmplitude = 1;
                app.constRun = 0;
                app.subfolder = [];
                app.sampleRate = 192000;
                app.configFilename = 'default';
                app.dt = 0.001;
                app.alpha = 0.0001;
                app.a = 1000;
                app.cr = 0.1;
                app.Ms = 100000;
                app.kp = 300;


                checkForNewAudioDevices(app, [])
                changeOutputChOne(app, [])
                changeInputChOne(app, [])


                audioDevConnectPresstoConnecttheAudioInterfaceButton(app,[])
                logMessage(app,'App opened.')
                close all
                clc
            catch ME
                logMessage(app,['Error within startupFcn function! - ', ME.message])
            end
        end

        % Button pushed function: PresstoConnecttheAudioInterfaceButton
        function audioDevConnectPresstoConnecttheAudioInterfaceButton(app, event)
            try
                app.PresstoConnecttheAudioInterfaceButton.Text = 'Connecting';
                logMessage(app,'Attempting to connect to audio device.')

                
                app.aDR = audioDeviceReader('Driver', 'DirectSound', ...
                                            'Device', app.inputCh1, ...
                                            'SampleRate', app.sampleRate, ...
                                            'BitDepth','16-bit integer', ...
                                            'SamplesPerFrame',app.bufferSize);

                app.aDW = audioDeviceWriter('Driver', 'DirectSound', ...
                                            'Device', app.outputCh1, ...
                                            'SampleRate', app.sampleRate, ...
                                            'BitDepth','16-bit integer', ...
                                            'SupportVariableSizeInput',true);

                app.aDR2 = audioDeviceReader('Driver', 'DirectSound', ...
                                            'Device', app.inputCh2, ...
                                            'SampleRate', app.sampleRate, ...
                                            'BitDepth','16-bit integer', ...
                                            'SamplesPerFrame',app.bufferSize);

                app.aDW2 = audioDeviceWriter('Driver', 'DirectSound', ...
                                            'Device', app.outputCh2, ...
                                            'SampleRate', app.sampleRate, ...
                                            'BitDepth','16-bit integer', ...
                                            'SupportVariableSizeInput',true);


                app.PresstoConnecttheAudioInterfaceButton.Text = 'Connected';
                logMessage(app,['Successfully connected to audio device. Channel 1 reader - ', app.inputCh1, '. Channel 2 reader - ', app.outputCh1,'.'])
            catch ME

                logMessage(app, 'Error within runMeasurement function!');
                logMessage(app, ['Message: ', ME.message]);
                logMessage(app, ['Identifier: ', ME.identifier]);
            

                for k = 1:length(ME.stack)
                    logMessage(app, sprintf('In %s at %s (line %d)', ...
                        ME.stack(k).name, ME.stack(k).file, ME.stack(k).line));
                end
           
                app.RunButton.Text = 'Error During Run!!!';
            end
        end

        % Value changed function: SPWMCarrierFreqHzEditField
        function updateEndFrequency(app, event)
            try
                app.SPWMCarrFreq = app.SPWMCarrierFreqHzEditField.Value;
                logMessage(app,['SPWM Carrier Frequency Updated To ',num2str(app.SPWMCarrFreq), ' Hz'])
            catch ME
                logMessage(app,['Error within updateEndFrequency function! - ', ME.message])
            end
        end

        % Value changed function: FrequencyHzEditField
        function updateStartFrequency(app, event)
            try
                app.startFreq = app.FrequencyHzEditField.Value;
                logMessage(app,['Start Frequency Updated To ',num2str(app.startFreq),' Hz'])
            catch ME
                logMessage(app,['Error within updateStartFrequency function! - ', ME.message])
            end
        end

        % Value changed function: OscilationTypeDropDown
        function osciTypeChanged(app, event)
            try
                app.osciType = app.OscilationTypeDropDown.Value;
                logMessage(app,['Oscilation Type Updated To ',app.osciType])

                if strcmp(app.osciType,'SPWM')
                    app.SPWMCarrierFreqHzEditField.Visible = 'on';
                    app.SPWMCarrierFreqHzEditFieldLabel.Visible = 'on';
                else
                    app.SPWMCarrierFreqHzEditField.Visible = 'off';
                    app.SPWMCarrierFreqHzEditFieldLabel.Visible = 'off';
                end
            catch ME
                logMessage(app,['Error within osciTypeChanged function! - ', ME.message])
            end
        end

        % Close request function: UIFigure
        function UIclosed(app, event)
            try
                release(app.aDW);release(app.aDR);release(app.aDW2);release(app.aDR2)
            catch ME
                logMessage(app,['Error during closing UI (likely due to no device being connected) - ', ME.message])
            end

            delete(app)
            logMessage(app,'App closed')
        end

        % Button pushed function: RunButton
        function runMeasurement(app, event)
            try
                if ~strcmp(app.PresstoConnecttheAudioInterfaceButton.Text, 'Connected') % Check if audio interface is connected
                    app.RunButton.Text = 'No Connection to the Audio Interface';
                    return
                end

                if strcmp(app.RunButton.Text, 'Run in Progress')                        % Check if there is already a run in progress
                    app.RunButton.Text = 'Already Running!';
                    app.constRun = 0;
                    return
                end


                app.constModeToggle.Text = 'Press to Stop';
                app.RunButton.Text = 'Run in Progress';
                logMessage(app,'Run started.')


                Source = generateSource(app,0,app.stCHAmplitude);                       % Create sources
                SourceOffset = generateSource(app,app.phaseOffset,app.ndCHAmplitude);
                lockout = 0;


                inputBuffer = [];                                                       % Initialise variables
                input2Buffer = [];
                outputBuffer = [];
                output2Buffer = [];
                output = [];
                construn = app.constRun;
                i = 0;


                while lockout == 0                                                      % Enter a loop, and begin to play audio. Check if audio is being recieved, and escape the loop when audio is recieved.
                    x = Source();
                    x2 = SourceOffset();
                    app.aDW(x);
                    app.aDW(x2);
                    temp = app.aDR();
                    output = [output; [temp,temp]];
                    if any(abs(output(:,1)) >= 0.002)                   %%% Potential bug here, if the recieved audio has a scale factor of 0.8 due to the physical system, lockout may not be triggered. Perhaps a better solution is needed.
                        lockout = 1;

                    end
                end
                app.runTimer = tic;                                                     % Start experimental timer


                while toc(app.runTimer) < app.runLength || app.constRun == 1            % Check if expermintal timer is less than requested run length, OR continuous mode is enabled
                    Source.Frequency = app.startFreq;
                    Source.Amplitude = app.stCHAmplitude;
                    SourceOffset.Frequency = app.startFreq;
                    SourceOffset.Amplitude = app.ndCHAmplitude;
                    % SourceOffset.PhaseOffset = app.PhaseOffset;
                    
                    x = Source();
                    x2 = SourceOffset();                                                % Generate new audio sources
                    
                    app.aDW(x);
                    app.aDW2(x2);
                    temp = app.aDR();                                     % Transmit and recieve audio, store these values in a buffer
                    temp2 = app.aDR2();
                    inputBuffer = [inputBuffer; x];
                    input2Buffer = [input2Buffer; x2];
                    outputBuffer = [outputBuffer; temp];
                    output2Buffer = [output2Buffer; temp2];
                    

                    switch i                                                            % Switch between plotting each tab of the UI, reduces computation time
                        case 0
                            livePlot(app,outputBuffer,output2Buffer,inputBuffer,input2Buffer,toc(app.runTimer),0,0)
                            i = 1;
                        case 1
                            livePlot(app,outputBuffer,output2Buffer,inputBuffer,input2Buffer,toc(app.runTimer),1,0)
                            i = 2;
                        case 2
                            livePlot(app,outputBuffer,output2Buffer,inputBuffer,input2Buffer,toc(app.runTimer),2,0)
                            i = 0;
                    end

                    drawnow limitrate                                                    % Required to update the UI, stop it from freezing in the loop
                end

                endTime = toc(app.runTimer);                                             % Store buffers as properties of the app (i dont know if this is still needed?)
                app.Input1Buffer = inputBuffer;
                app.Input2Buffer = input2Buffer;
                app.Output1Buffer = outputBuffer;
                app.Output2Buffer = output2Buffer;


                dataAnalysis(app, app.Output1Buffer, app.Output2Buffer, app.Input1Buffer, app.Input2Buffer, endTime,0);       % Run full analysis

                app.RunButton.Text = 'Run Finished!';
                app.constRun = ~construn;
                contFuc(app,[])
                % release(app.aPR)                                                            % Needed to fix a bug in R2023a, where audio devices are not properly handled when transmitting data for the second time
                release(app.aDW);release(app.aDR);release(app.aDW2);release(app.aDR2);
                audioDevConnectPresstoConnecttheAudioInterfaceButton(app, [])

                app.RunButton.Text = 'Run';
            catch ME

                logMessage(app, 'Error within runMeasurement function!');
                logMessage(app, ['Message: ', ME.message]);
                logMessage(app, ['Identifier: ', ME.identifier]);
            

                for k = 1:length(ME.stack)
                    logMessage(app, sprintf('In %s at %s (line %d)', ...
                        ME.stack(k).name, ME.stack(k).file, ME.stack(k).line));
                end
           
                app.RunButton.Text = 'Error During Run!!!';
            end
        end

        % Value changed function: runLengthButton
        function runLengthChanged(app, event)
            try
                app.runLength = app.runLengthButton.Value;
                logMessage(app,['Run Length Updated To ',num2str(app.runLength),'s'])
            catch ME
                logMessage(app,['Error within runLengthChanged function! - ', ME.message])
            end
        end

        % Value changed function: BufferSizeDropDown
        function updateBufferSize(app, event)
            try
                app.bufferSize = str2double(app.BufferSizeDropDown.Value);
                logMessage(app,['Buffer Size Updated To ',num2str(app.bufferSize)])
                % release(app.aPR) 
                release(app.aDW); release(app.aDR);release(app.aDW2);release(app.aDR2);
                app.PresstoConnecttheAudioInterfaceButton.Text = 'Buffer Size Updated! Press to Reconnect.';        % Reconnect to the audio device (only needed if new buffer size value is <= 8192)
                audioDevConnectPresstoConnecttheAudioInterfaceButton(app, [])
            catch ME
                logMessage(app,['Error within updateBufferSize function! - ', ME.message])
            end
        end

        % Value changed function: PhaseOffset
        function updatePhaseOffset(app, event)
            try
                app.phaseOffset = app.PhaseOffset.Value;
                logMessage(app,['Phase Offset Updated To ',num2str(app.phaseOffset),' deg'])
            catch ME
                logMessage(app,['Error within updatePhaseOffset function! - ', ME.message])
            end
        end

        % Value changed function: stCHAmplitudeEditField
        function stChAmplitudeChange(app, event)
            try
                app.stCHAmplitude = app.stCHAmplitudeEditField.Value;
                logMessage(app,['1st Channel Amplitude Updated To ',num2str(app.stCHAmplitude)])
            catch ME
                logMessage(app,['Error within 1stChAmplitudeChange function! - ', ME.message])
            end
        end

        % Value changed function: ndCHAmplitudeEditField
        function ndChAmplitudeChange(app, event)
            try
                app.ndCHAmplitude = app.ndCHAmplitudeEditField.Value;
                logMessage(app,['2nd Channel Amplitude Updated To ',num2str(app.ndCHAmplitude)])
            catch ME
                logMessage(app,['Error within 2ndCHAmplitudeChange function! - ', ME.message])
            end
        end

        % Button pushed function: SaveResultsPlotsButton
        function saveResultsAndPlot(app, event)
            try
                app.SaveResultsPlotsButton.Text = 'Saving Results ...';         % Generate the directory variables 
                resultsFolder = fullfile(pwd, ['results/', app.subfolder]);
                resultsFile   = fullfile(resultsFolder, 'results.txt');

                if ~exist(resultsFolder, 'dir')                                 % Ensure the requested folder exists
                    mkdir(resultsFolder);
                end

                fid = fopen(resultsFile, 'w');

                fprintf(fid, "%s\n", app.ResultsLabel.Text);                    % Write the results label to the results file
                fclose(fid);
                logMessage(app, ["Wrote file: " , resultsFile]);

                exportgraphics(app.BHCurveTab, fullfile(resultsFolder, 'BHCurve.png'))              % Export the tabs of the UI as pngs
                exportgraphics(app.WaveformsTab, fullfile(resultsFolder, 'Waveforms.png'))
                exportgraphics(app.ProcessedWaveformsTab, fullfile(resultsFolder, 'TransformedWaveforms.png'))

                logMessage(app,'Graphics succesfully exported!')

                t = table(app.Time',app.Output1Buffer,app.Input1Buffer,app.Output2Buffer,app.Input2Buffer,...       % Write the timeseries of the experiment to a .csv
                    'VariableNames',{'Time','CH1 Recorded','CH1 Transmit','CH2 Recorded','CH2 Transmit'});
                writetable(t,fullfile(resultsFolder,'data.csv'));


                app.SaveResultsPlotsButton.Text = ['Successfully saved to ', resultsFolder];
                app.SaveResultsPlotsButton.Text = 'Save Results & Plots';
            catch ME
                logMessage(app,['Error within saveResultsAndPlots function! - ', ME.message])
            end
        end

        % Button pushed function: constModeToggle
        function contFuc(app, event)
            try
                if app.constRun == 0
                    app.constRun = 1;
                    app.constModeToggle.Text = 'Continuous Mode Enabled';
                    logMessage(app,'Continuous Mode Enabled')
                else
                    app.constRun = 0;
                    app.constModeToggle.Text = 'Continuous Mode Disabled';
                    logMessage(app,'Continuous Mode Disabled')
                end
            catch ME
                logMessage(app,['Error within contFuc function! - ', ME.message])
            end
        end

        % Value changed function: FolderToSaveToEditField
        function changeResultsFolder(app, event)
            try
                app.subfolder = app.FolderToSaveToEditField.Value;
                logMessage(app,['New results save location - ', app.subfolder]);
            catch ME
                logMessage(app,['Error within changeResultsFolder function! - ', ME.message])
            end
        end

        % Value changed function: SampleRateEditField
        function changeSampleRate(app, event)
            try
                app.sampleRate = app.SampleRateEditField.Value;
                audioDevConnectPresstoConnecttheAudioInterfaceButton(app, [])
                logMessage(app,['Sample Rate Updated to ', num2str(app.sampleRate),'Hz'])
            catch ME
                logMessage(app,['Error within changeSampleRate function! - ', ME.message])
            end
        end

        % Button pushed function: saveCurrentConfig
        function saveConfig(app, event)
            try
                configDir = fullfile(pwd, 'experiment configs');
                if ~exist(configDir, 'dir')
                    mkdir(configDir);
                end
                configPath = fullfile(configDir, [app.configFilename,'.json']);


                settings = struct( ...
                                    'SPWMCarrFreq', app.SPWMCarrFreq, ...
                                    'startFreq', app.startFreq, ...
                                    'osciType', app.osciType, ...
                                    'runLength', app.runLength, ...
                                    'bufferSize', app.bufferSize, ...
                                    'phaseOffset', app.phaseOffset, ...
                                    'stCHAmplitude', app.stCHAmplitude, ...
                                    'ndCHAmplitude', app.ndCHAmplitude, ...
                                    'sampleRate', app.sampleRate);
        
                % Convert to JSON and save
                jsonStr = jsonencode(settings);
                fid = fopen(configPath,'w');
                fwrite(fid, jsonStr, 'char');
                fclose(fid);
            catch ME
                logMessage(app,['Error within saveConfig function - ' ME.message])
            end
        end

        % Value changed function: ExpConfigFilenameEditField
        function changeConfigFilename(app, event)
            try
                app.configFilename = app.ExpConfigFilenameEditField.Value;
            catch ME
                logMessage(app,['Error within changeConfigFilename function - ' ME.message])
            end
        end

        % Button pushed function: loadCurrentConfig
        function loadConfig(app, event)
            filename = app.configFilename; % Needs to be changed 
            filename = [filename,'.json'];

            configDir  = fullfile(pwd, 'experiment configs');
            configPath = fullfile(configDir, filename);

            txt = fileread(configPath);
            data = jsondecode(txt);
            fields = {'SPWMCarrFreq','startFreq','osciType','runLength','bufferSize', ...
                      'phaseOffset','stCHAmplitude','ndCHAmplitude','sampleRate'};
            for k = 1:numel(fields)
                if isfield(data, fields{k})
                    app.(fields{k}) = data.(fields{k});
                end
            end

            app.SPWMCarrierFreqHzEditField.Value = app.SPWMCarrFreq;
            app.FrequencyHzEditField.Value = app.startFreq;
            app.OscilationTypeDropDown.Value = app.osciType;
            app.runLengthButton.Value = app.runLength;
            app.PhaseOffset.Value = app.phaseOffset;
            app.stCHAmplitudeEditField.Value = app.stCHAmplitude;
            app.ndCHAmplitudeEditField.Value = app.ndCHAmplitude;
            app.SampleRateEditField.Value = app.sampleRate;
        end

        % Button pushed function: OscilloscopeOffButton
        function osciloscopeButton(app, event)
            try
                if strcmp(app.OscilloscopeOffButton.Text,'Oscilloscope Off')
                    app.OscilloscopeOffButton.Text = 'Oscilloscope On';
                    app.oscilloscope = 1;
                elseif strcmp (app.OscilloscopeOffButton.Text,'Oscilloscope On')
                    app.OscilloscopeOffButton.Text = 'Oscilloscope Off';
                    app.oscilloscope = 0;                
                end
                osciloscopeHelperFunc(app)
            catch ME
                logMessage(app, 'Error within runMeasurement function!');
                logMessage(app, ['Message: ', ME.message]);
                logMessage(app, ['Identifier: ', ME.identifier]);
            

                for k = 1:length(ME.stack)
                    logMessage(app, sprintf('In %s at %s (line %d)', ...
                        ME.stack(k).name, ME.stack(k).file, ME.stack(k).line));
                end
            end


        end

        % Callback function: dtSlider, dtSlider
        function dtChanged(app, event)
            try
                app.dt = app.dtSlider.Value;
                logMessage(app,['dt updated to ',num2str(app.dt),' sec'])
                simPlot(app)
            catch ME
                logMessage(app,['Error within dtChanged function! - ', ME.message])
            end
            
        end

        % Callback function: alphaSlider, alphaSlider
        function alphaChanged(app, event)
            try
                app.alpha = app.alphaSlider.Value;
                logMessage(app,['alpha updated to ',num2str(app.alpha)])
                simPlot(app)
            catch ME
                logMessage(app,['Error within alphaChanged function! - ', ME.message])
            end
        end

        % Callback function: kpSlider, kpSlider
        function kpChanged(app, event)
            try
                app.kp = app.kpSlider.Value;
                logMessage(app,['kp updated to ',num2str(app.kp)])
                simPlot(app)
            catch ME
                logMessage(app,['Error within kpChanged function! - ', ME.message])
            end
        end

        % Callback function: aSlider, aSlider
        function aChanged(app, event)
            try
                app.a = app.aSlider.Value;
                logMessage(app,['a updated to ',num2str(app.a)])
                simPlot(app)
            catch ME
                logMessage(app,['Error within aChanged function! - ', ME.message])
            end 
        end

        % Callback function: MsSlider, MsSlider
        function msChanged(app, event)
            try
                app.Ms = app.MsSlider.Value;
                logMessage(app,['Ms updated to ',num2str(app.Ms)])
                simPlot(app)
            catch ME
                logMessage(app,['Error within msChanged function! - ', ME.message])
            end
        end

        % Callback function: crSlider, crSlider
        function crChanged(app, event)
            try
                app.cr = app.crSlider.Value;
                logMessage(app,['cr updated to ',num2str(app.cr)])
                simPlot(app)
            catch ME
                logMessage(app,['Error within crChanged function! - ', ME.message])
            end
        end

        % Button down function: SimulationTab
        function simTabClick(app, event)
            try
                simPlot(app)
            catch ME
                logMessage(app,['Error within simTabClick function! - ', ME.message])
            end
        end

        % Button pushed function: CheckForNewAudioDevicesButton
        function checkForNewAudioDevices(app, event)
            devices = audiodevinfo;

            inputDevices = {devices.input(:).Name};
            outputDevices = {devices.output(:).Name};
            inputDevices  = regexprep(inputDevices,  '\s*\([^)]*\)$', '');
            outputDevices = regexprep(outputDevices, '\s*\([^)]*\)$', '');

            app.AudioOutputChannel1DropDown.Items = outputDevices;
            app.AudioInputChannel1DropDown.Items = inputDevices;
            app.AudioOutputChannel2DropDown.Items = outputDevices;
            app.AudioInputChannel2DropDown.Items = inputDevices;

        end

        % Value changed function: AudioOutputChannel1DropDown
        function changeOutputChOne(app, event)
            try
                app.outputCh1 = app.AudioOutputChannel1DropDown.Value;
                logMessage(app,['outputCh1 updated to ',app.outputCh1])
                audioDevConnectPresstoConnecttheAudioInterfaceButton(app,[])
            catch ME
                logMessage(app,['Error within changeOutputChOne function! - ', ME.message])
            end
        end

        % Value changed function: AudioInputChannel1DropDown
        function changeInputChOne(app, event)
            try
                app.inputCh1 = app.AudioInputChannel1DropDown.Value;
                logMessage(app,['inputCh1 updated to ',app.inputCh1])
                audioDevConnectPresstoConnecttheAudioInterfaceButton(app,[])
            catch ME
                logMessage(app,['Error within changeInputChOne function! - ', ME.message])
            end
        end

        % Value changed function: AudioOutputChannel2DropDown
        function changeOutputChTwo(app, event)
            try
                app.outputCh2 = app.AudioOutputChannel2DropDown.Value;
                logMessage(app,['outputCh1 updated to ',app.outputCh2])
                audioDevConnectPresstoConnecttheAudioInterfaceButton(app,[])
            catch ME
                logMessage(app,['Error within changeOutputChTwo function! - ', ME.message])
            end
        end

        % Value changed function: AudioInputChannel2DropDown
        function changeInputChTwo(app, event)
            try
                app.inputCh2 = app.AudioInputChannel2DropDown.Value;
                logMessage(app,['inputCh2 updated to ',app.inputCh2])
                audioDevConnectPresstoConnecttheAudioInterfaceButton(app,[])
            catch ME
                logMessage(app,['Error within changeInputChTwo function! - ', ME.message])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIclosed, true);
            app.SettingsTabs = uitabgroup(app.UIFigure);
            app.RunTab = uitab(app.SettingsTabs);
            app.RunTab.Title = 'Run Exp';
            app.PresstoConnecttheAudioInterfaceButton = uibutton(app.RunTab, 'push');
            app.PresstoConnecttheAudioInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @audioDevConnectPresstoConnecttheAudioInterfaceButton, true);
            app.PresstoConnecttheAudioInterfaceButton.Text = 'Press to Connect the Audio Interface';
            app.RunLengthsLabel = uilabel(app.RunTab);
            app.RunLengthsLabel.Text = 'Run Length (s)';
            app.runLengthButton = uieditfield(app.RunTab, 'numeric');
            app.runLengthButton.ValueChangedFcn = createCallbackFcn(app, @runLengthChanged, true);
            app.runLengthButton.Value = 0.1;
            app.RunButton = uibutton(app.RunTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runMeasurement, true);
            app.RunButton.Text = 'Run ';
            app.ResultsLabel = uilabel(app.RunTab);
            app.ResultsLabel.Text = 'Results:';
            app.SaveResultsPlotsButton = uibutton(app.RunTab, 'push');
            app.SaveResultsPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @saveResultsAndPlot, true);
            app.SaveResultsPlotsButton.Text = 'Save Results & Plots';
            app.constModeToggle = uibutton(app.RunTab, 'push');
            app.constModeToggle.ButtonPushedFcn = createCallbackFcn(app, @contFuc, true);
            app.constModeToggle.Text = 'Continuous Mode Disabled';
            app.FolderToSaveToEditFieldLabel = uilabel(app.RunTab);
            app.FolderToSaveToEditFieldLabel.Text = 'Folder To Save To';
            app.FolderToSaveToEditField = uieditfield(app.RunTab, 'text');
            app.FolderToSaveToEditField.ValueChangedFcn = createCallbackFcn(app, @changeResultsFolder, true);
            app.OscilloscopeOffButton = uibutton(app.RunTab, 'push');
            app.OscilloscopeOffButton.ButtonPushedFcn = createCallbackFcn(app, @osciloscopeButton, true);
            app.OscilloscopeOffButton.Text = 'Oscilloscope Off';
            app.SourcesTab = uitab(app.SettingsTabs);
            app.SourcesTab.Title = 'Sources';
            app.OscilationTypeDropDownLabel = uilabel(app.SourcesTab);
            app.OscilationTypeDropDownLabel.Text = 'Oscilation Type';
            app.OscilationTypeDropDown = uidropdown(app.SourcesTab);
            app.OscilationTypeDropDown.Items = {'sine', 'square', 'sawtooth', 'White Noise', 'SPWM'};
            app.OscilationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @osciTypeChanged, true);
            app.OscilationTypeDropDown.Value = 'sine';
            app.FrequencyHzEditFieldLabel = uilabel(app.SourcesTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SourcesTab, 'numeric');
            app.FrequencyHzEditField.Limits = [10 192000];
            app.FrequencyHzEditField.ValueDisplayFormat = '%d';
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @updateStartFrequency, true);
            app.FrequencyHzEditField.Value = 10;
            app.SPWMCarrierFreqHzEditFieldLabel = uilabel(app.SourcesTab);
            app.SPWMCarrierFreqHzEditFieldLabel.Visible = 'off';
            app.SPWMCarrierFreqHzEditFieldLabel.Text = 'SPWM Carrier Freq (Hz)';
            app.SPWMCarrierFreqHzEditField = uieditfield(app.SourcesTab, 'numeric');
            app.SPWMCarrierFreqHzEditField.Limits = [10 192000];
            app.SPWMCarrierFreqHzEditField.ValueDisplayFormat = '%d';
            app.SPWMCarrierFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @updateEndFrequency, true);
            app.SPWMCarrierFreqHzEditField.Visible = 'off';
            app.SPWMCarrierFreqHzEditField.Value = 500;
            app.stCHAmplitudeEditFieldLabel = uilabel(app.SourcesTab);
            app.stCHAmplitudeEditFieldLabel.Text = '1st CH Amplitude';
            app.stCHAmplitudeEditField = uieditfield(app.SourcesTab, 'numeric');
            app.stCHAmplitudeEditField.Limits = [0 1];
            app.stCHAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @stChAmplitudeChange, true);
            app.stCHAmplitudeEditField.Value = 1;
            app.ndCHPhaseOffsetdegEditFieldLabel = uilabel(app.SourcesTab);
            app.ndCHPhaseOffsetdegEditFieldLabel.Text = '2nd CH Phase Offset (deg)';
            app.PhaseOffset = uieditfield(app.SourcesTab, 'numeric');
            app.PhaseOffset.Limits = [0 360];
            app.PhaseOffset.ValueChangedFcn = createCallbackFcn(app, @updatePhaseOffset, true);
            app.PhaseOffset.Value = 45;
            app.ndCHAmplitudeEditFieldLabel = uilabel(app.SourcesTab);
            app.ndCHAmplitudeEditFieldLabel.Text = '2nd CH Amplitude';
            app.ndCHAmplitudeEditField = uieditfield(app.SourcesTab, 'numeric');
            app.ndCHAmplitudeEditField.Limits = [0 1];
            app.ndCHAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @ndChAmplitudeChange, true);
            app.ndCHAmplitudeEditField.Value = 1;
            app.AdvTab = uitab(app.SettingsTabs);
            app.AdvTab.Title = 'Audio Device';
            app.BufferSizeDropDownLabel = uilabel(app.AdvTab);
            app.BufferSizeDropDownLabel.Text = 'Buffer Size';
            app.BufferSizeDropDown = uidropdown(app.AdvTab);
            app.BufferSizeDropDown.Items = {'32', '64', '128', '256', '512', '1024', '2048', '4096', '8192', '24000', '48000', '96000', '192000', '384000'};
            app.BufferSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateBufferSize, true);
            app.BufferSizeDropDown.Value = '192000';
            app.SampleRateEditFieldLabel = uilabel(app.AdvTab);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.AdvTab, 'numeric');
            app.SampleRateEditField.Limits = [10 192000];
            app.SampleRateEditField.ValueDisplayFormat = '%d';
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @changeSampleRate, true);
            app.SampleRateEditField.Value = 192000;
            app.AudioOuputChannel1Label = uilabel(app.AdvTab);
            app.AudioOuputChannel1Label.Text = 'Audio Output Channel 1';
            app.AudioOutputChannel1DropDown = uidropdown(app.AdvTab);
            app.AudioOutputChannel1DropDown.ValueChangedFcn = createCallbackFcn(app, @changeOutputChOne, true);
            app.CheckForNewAudioDevicesButton = uibutton(app.AdvTab, 'push');
            app.CheckForNewAudioDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @checkForNewAudioDevices, true);
            app.CheckForNewAudioDevicesButton.Text = 'Check For New Audio Devices';
            app.AudioInputChannel1DropDownLabel = uilabel(app.AdvTab);
            app.AudioInputChannel1DropDownLabel.Text = 'Audio Input Channel 1';
            app.AudioInputChannel1DropDown = uidropdown(app.AdvTab);
            app.AudioInputChannel1DropDown.ValueChangedFcn = createCallbackFcn(app, @changeInputChOne, true);
            app.AudioInputChannel2Label = uilabel(app.AdvTab);
            app.AudioInputChannel2Label.Text = 'Audio Input Channel 2';
            app.AudioInputChannel2DropDown = uidropdown(app.AdvTab);
            app.AudioInputChannel2DropDown.ValueChangedFcn = createCallbackFcn(app, @changeInputChTwo, true);
            app.AudioOutputChannel2DropDownLabel = uilabel(app.AdvTab);
            app.AudioOutputChannel2DropDownLabel.Text = 'Audio Output Channel 2';
            app.AudioOutputChannel2DropDown = uidropdown(app.AdvTab);
            app.AudioOutputChannel2DropDown.ValueChangedFcn = createCallbackFcn(app, @changeOutputChTwo, true);
            app.Plan = uitab(app.SettingsTabs);
            app.Plan.Title = 'Plan Exp';
            app.saveCurrentConfig = uibutton(app.Plan, 'push');
            app.saveCurrentConfig.ButtonPushedFcn = createCallbackFcn(app, @saveConfig, true);
            app.saveCurrentConfig.Text = 'Save Current Experiment Config ';
            app.ExpConfigFilenameEditFieldLabel = uilabel(app.Plan);
            app.ExpConfigFilenameEditFieldLabel.Text = 'Exp Config Filename';
            app.ExpConfigFilenameEditField = uieditfield(app.Plan, 'text');
            app.ExpConfigFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @changeConfigFilename, true);
            app.loadCurrentConfig = uibutton(app.Plan, 'push');
            app.loadCurrentConfig.ButtonPushedFcn = createCallbackFcn(app, @loadConfig, true);
            app.loadCurrentConfig.Text = 'Load Experiment Config ';
            app.PlotTabs = uitabgroup(app.UIFigure);
            app.WaveformsTab = uitab(app.PlotTabs);
            app.WaveformsTab.Title = 'Waveforms';
            app.ResultsPlot = uiaxes(app.WaveformsTab);
            title(app.ResultsPlot, 'Channel 1 Run Results')
            xlabel(app.ResultsPlot, 'Time Elapsed (s)')
            ylabel(app.ResultsPlot, 'Amplitude')
            zlabel(app.ResultsPlot, 'Z')
            app.ResultsPlot2 = uiaxes(app.WaveformsTab);
            title(app.ResultsPlot2, 'Channel 2 Run Results')
            xlabel(app.ResultsPlot2, 'Time Elapsed (s)')
            ylabel(app.ResultsPlot2, 'Amplitude')
            zlabel(app.ResultsPlot2, 'Z')
            app.ProcessedWaveformsTab = uitab(app.PlotTabs);
            app.ProcessedWaveformsTab.Title = 'Processed Waveforms';
            app.ProcessedWaveforms = uiaxes(app.ProcessedWaveformsTab);
            title(app.ProcessedWaveforms, 'Integrated Waveform of Channel 2')
            xlabel(app.ProcessedWaveforms, 'Time Elapsed (s)')
            ylabel(app.ProcessedWaveforms, 'Amplitude')
            zlabel(app.ProcessedWaveforms, 'Z')
            app.ProcessedWaveforms2 = uiaxes(app.ProcessedWaveformsTab);
            title(app.ProcessedWaveforms2, 'Integrated Waveform of Channel 1')
            xlabel(app.ProcessedWaveforms2, 'Time Elapsed (s)')
            ylabel(app.ProcessedWaveforms2, 'Amplitude')
            zlabel(app.ProcessedWaveforms2, 'Z')
            app.ProcessedWaveforms3 = uiaxes(app.ProcessedWaveformsTab);
            title(app.ProcessedWaveforms3, 'Offset Waveform of Channel 2')
            xlabel(app.ProcessedWaveforms3, 'Time Elapsed (s)')
            ylabel(app.ProcessedWaveforms3, 'Amplitude')
            zlabel(app.ProcessedWaveforms3, 'Z')
            app.ProcessedWaveforms4 = uiaxes(app.ProcessedWaveformsTab);
            title(app.ProcessedWaveforms4, 'Offset Waveform of Channel 1')
            xlabel(app.ProcessedWaveforms4, 'Time Elapsed (s)')
            ylabel(app.ProcessedWaveforms4, 'Amplitude')
            zlabel(app.ProcessedWaveforms4, 'Z')
            app.BHCurveTab = uitab(app.PlotTabs);
            app.BHCurveTab.Title = 'B-H Curve';
            app.BHCurve = uiaxes(app.BHCurveTab);
            title(app.BHCurve, 'B-H Curve of Previous Run')
            xlabel(app.BHCurve, 'CH1 Integrated Signal')
            ylabel(app.BHCurve, 'CH2 Integrated Signal')
            zlabel(app.BHCurve, 'Z')
            app.SimulationTab = uitab(app.PlotTabs);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationTab.ButtonDownFcn = createCallbackFcn(app, @simTabClick, true);
            app.simBHPlot = uiaxes(app.SimulationTab);
            title(app.simBHPlot, 'Simulated BH Curve')
            xlabel(app.simBHPlot, 'H in kA/m')
            ylabel(app.simBHPlot, 'B in Tesla')
            zlabel(app.simBHPlot, 'Z')
            app.simEnergy = uiaxes(app.SimulationTab);
            title(app.simEnergy, 'Energy Dissipation')
            xlabel(app.simEnergy, 'Amplitude')
            ylabel(app.simEnergy, 'Power')
            zlabel(app.simEnergy, 'Z')
            app.aSliderLabel = uilabel(app.SimulationTab);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.SimulationTab);
            app.aSlider.Limits = [100 2000];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aChanged, true);
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aChanged, true);
            app.aSlider.Value = 1000;
            app.MsSliderLabel = uilabel(app.SimulationTab);
            app.MsSliderLabel.Text = 'Ms';
            app.MsSlider = uislider(app.SimulationTab);
            app.MsSlider.Limits = [10000 1000000];
            app.MsSlider.ValueChangedFcn = createCallbackFcn(app, @msChanged, true);
            app.MsSlider.ValueChangingFcn = createCallbackFcn(app, @msChanged, true);
            app.MsSlider.Value = 10000;
            app.crSliderLabel = uilabel(app.SimulationTab);
            app.crSliderLabel.Text = 'cr';
            app.crSlider = uislider(app.SimulationTab);
            app.crSlider.Limits = [0.005 0.9999];
            app.crSlider.ValueChangedFcn = createCallbackFcn(app, @crChanged, true);
            app.crSlider.ValueChangingFcn = createCallbackFcn(app, @crChanged, true);
            app.crSlider.Value = 0.1;
            app.kpSliderLabel = uilabel(app.SimulationTab);
            app.kpSliderLabel.Text = 'kp';
            app.kpSlider = uislider(app.SimulationTab);
            app.kpSlider.Limits = [100 10000];
            app.kpSlider.ValueChangedFcn = createCallbackFcn(app, @kpChanged, true);
            app.kpSlider.ValueChangingFcn = createCallbackFcn(app, @kpChanged, true);
            app.kpSlider.Value = 300;
            app.alphaSliderLabel = uilabel(app.SimulationTab);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.SimulationTab);
            app.alphaSlider.Limits = [1e-05 0.1];
            app.alphaSlider.MajorTicks = [1e-05 0.02 0.04 0.06 0.08 0.1];
            app.alphaSlider.MajorTickLabels = {'1e-05', '0.02', '0.04', '0.06', '0.08', '0.1'};
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaChanged, true);
            app.alphaSlider.ValueChangingFcn = createCallbackFcn(app, @alphaChanged, true);
            app.alphaSlider.Value = 0.0001;
            app.dtSliderLabel = uilabel(app.SimulationTab);
            app.dtSliderLabel.Text = 'dt';
            app.dtSlider = uislider(app.SimulationTab);
            app.dtSlider.Limits = [0.0001 0.003];
            app.dtSlider.ValueChangedFcn = createCallbackFcn(app, @dtChanged, true);
            app.dtSlider.ValueChangingFcn = createCallbackFcn(app, @dtChanged, true);
            app.dtSlider.Value = 0.001;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end